classdef appDataEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ViewerPanel             matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        UIAxesCh4               matlab.ui.control.UIAxes
        UIAxesCh5               matlab.ui.control.UIAxes
        UIAxesCh6               matlab.ui.control.UIAxes
        UIAxesCh7               matlab.ui.control.UIAxes
        Panel                   matlab.ui.container.Panel
        StartLabel              matlab.ui.control.Label
        StopLabel               matlab.ui.control.Label
        SliderStart             matlab.ui.control.Slider
        SliderStop              matlab.ui.control.Slider
        EditorPanel             matlab.ui.container.Panel
        SelectedLabel           matlab.ui.control.Label
        DoneButton              matlab.ui.control.Button
        NextButton              matlab.ui.control.Button
        SelectDataButton        matlab.ui.control.Button
        GroupActionButtonGroup  matlab.ui.container.ButtonGroup
        DeleteButton            matlab.ui.control.RadioButton
        SelectButton            matlab.ui.control.RadioButton
        DisplayChannelsPanel    matlab.ui.container.Panel
        Channel1CheckBox        matlab.ui.control.CheckBox
        Channel2CheckBox        matlab.ui.control.CheckBox
        Channel3CheckBox        matlab.ui.control.CheckBox
        Channel4CheckBox        matlab.ui.control.CheckBox
        Channel5CheckBox        matlab.ui.control.CheckBox
        Channel6CheckBox        matlab.ui.control.CheckBox
        Channel7CheckBox        matlab.ui.control.CheckBox
        Channel8CheckBox        matlab.ui.control.CheckBox
        Channel9CheckBox        matlab.ui.control.CheckBox
        Channel10CheckBox       matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        files
        path
        dataRaw
        dataNew
        pLineStart
        pLineStop
        dataChunk
    end
    
    properties (Access = private)
        xLimit = zeros(0, 2);
        xLimitOrig
        yLimitOrig
        loopCheckingXLimitFlag = false
        colorLineStart = [153,0,0]/255  % crimson
        colorLineStop = [80,220,100]/255  % emerald
    end
    
    methods (Access = private)
        
        function plotData(app)
            for i = 1:length(app.GridLayout.Children)
                hold(app.GridLayout.Children(i,1), 'off')
                plot(app.GridLayout.Children(i,1), app.dataRaw(2:end, i+3));
                hold(app.GridLayout.Children(i,1), 'on')
                zoom(app.GridLayout.Children(i,1), 'on');
                app.xLimitOrig(i,:) = app.GridLayout.Children(i,1).XLim;
                app.yLimitOrig(i,:) = app.GridLayout.Children(i,1).YLim;
            end
            app.xLimit = app.GridLayout.Children(1,1).XLim;
        end
        
        function pLine = plotLines(app, value, colorCode)
            for i = 1:length(app.GridLayout.Children)
                delete(findobj(app.GridLayout.Children(i,1).Children,'Color',colorCode,'LineWidth',0.5));
                xPoint = floor(app.GridLayout.Children(i,1).XLim(1,1) + diff(app.GridLayout.Children(i,1).XLim) * value / 100);
                yLimitTemp = app.GridLayout.Children(i,1).YLim;
                pLine = plot(app.GridLayout.Children(i,1), repmat(xPoint,1,2), app.yLimitOrig(i,:), 'Color', colorCode, 'LineWidth', 0.5);
                ylim(app.GridLayout.Children(i,1), yLimitTemp);
            end
        end
        
        function linkAxes(app)
            if ~app.loopCheckingXLimitFlag
                app.loopCheckingXLimitFlag = true;
                while exist('app', 'var')
                    try
                        for i = 1:length(app.GridLayout.Children)
                            if app.GridLayout.Children(i,1).XLim ~= app.xLimit
                                app.xLimit = app.GridLayout.Children(i,1).XLim;
                                for j = 1:length(app.GridLayout.Children)
                                    app.GridLayout.Children(j,1).XLim = app.xLimit;
                                    if any(all(app.xLimit == app.xLimitOrig, 2))
                                        ylim(app.GridLayout.Children(j,1), app.yLimitOrig(j,:))
                                    else
                                        ylim(app.GridLayout.Children(j,1), 'auto')
                                    end
                                end
                                break
                            end
                        end
                        drawnow
                    catch
                        break
                    end
                end
            end
        end
        
        function addDataChunk(app)
            if app.pLineStart.XData(1,1) < app.pLineStop.XData(1,1)
                app.dataChunk = [app.dataChunk; app.pLineStart.XData(1,1), app.pLineStop.XData(1,1)];
                app.SelectedLabel.Text = sprintf('Selected: %d', size(app.dataChunk, 1));
                plotSelectedLines(app, app.dataChunk(end, :));
                
                disp(app.dataChunk)
            end
        end
        
        function [pLineStart, pLineStop] = plotSelectedLines(app, xPoint)
            for i = 1:length(app.GridLayout.Children)
                pLineStart = plot(app.GridLayout.Children(i,1), repmat(xPoint(1,1),1,2), app.yLimitOrig(i,:),'Color', app.colorLineStart, 'LineWidth', 2, 'LineStyle', '--');
                pLineStop = plot(app.GridLayout.Children(i,1), repmat(xPoint(1,2),1,2), app.yLimitOrig(i,:),'Color', app.colorLineStop, 'LineWidth', 2, 'LineStyle', '--');
                plot(app.GridLayout.Children(i,1), repmat(xPoint(1,1),1,2), app.yLimitOrig(i,:),'Color', app.colorLineStart, 'LineWidth', 1);
                plot(app.GridLayout.Children(i,1), repmat(xPoint(1,2),1,2), app.yLimitOrig(i,:),'Color', app.colorLineStop, 'LineWidth', 1);
            end
        end
    
        
        function getDataNew(app)
            locsTempAll = zeros(1,0);
            for i = 1:size(app.dataChunk, 1)
                locsTemp = app.dataChunk(i,1) : app.dataChunk(i,2);
                locsTempAll = [locsTempAll, locsTemp];
            end
            switch app.GroupActionButtonGroup.SelectedObject.Text
                case 'Select'
                    app.dataNew = app.dataRaw(locsTempAll, :);
                case 'Delete'
                    app.dataNew = app.dataRaw;
                    app.dataNew(locsTempAll, :) = [];
            end
        end
        
        function exportDataNew(app)
            [files, path] = uiputfile('.csv', 'Save as...', ['edited', app.files{1,1}]);
            if files ~= 0
                popMsg('Saving file...');
                writematrix(app.dataNew, fullfile(path, files));
                popMsg('Finished saving...');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            linkAxes(app);
        end

        % Button pushed function: SelectDataButton
        function SelectDataButtonPushed(app, event)
            try
                [app.files, app.path] = selectFiles('Select Data to Edit...');  % select files
                popMsg('Loading data...')
                app.dataRaw = dlmread(fullfile(app.path,app.files{1,1}),',');
                plotData(app);
                deleteMsgBox;
                drawnow
            catch
            end
%             linkAxes(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            addDataChunk(app);
        end

        % Value changed function: SliderStart
        function SliderStartValueChanged(app, event)
            value = app.SliderStart.Value;
            app.pLineStart = plotLines(app, value, app.colorLineStart);
        end

        % Value changed function: SliderStop
        function SliderStopValueChanged(app, event)
            value = app.SliderStop.Value;
            app.pLineStop = plotLines(app, value, app.colorLineStop);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            getDataNew(app);
            exportDataNew(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'n'
                    app.pLineStart = plotLines(app, app.SliderStart.Value, app.colorLineStart);
                    app.pLineStop = plotLines(app, app.SliderStop.Value, app.colorLineStop);
                    addDataChunk(app);
                otherwise
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ViewerPanel = uipanel(app.UIFigure);
            app.ViewerPanel.Title = 'Viewer';
            app.GridLayout = uigridlayout(app.ViewerPanel);
            app.UIAxesCh4 = uiaxes(app.GridLayout);
            title(app.UIAxesCh4, 'Channel 4')
            xlabel(app.UIAxesCh4, '')
            ylabel(app.UIAxesCh4, 'Amplitude')
            app.UIAxesCh5 = uiaxes(app.GridLayout);
            title(app.UIAxesCh5, 'Channel 5')
            xlabel(app.UIAxesCh5, '')
            ylabel(app.UIAxesCh5, 'Amplitude')
            app.UIAxesCh6 = uiaxes(app.GridLayout);
            title(app.UIAxesCh6, 'Channel 6')
            xlabel(app.UIAxesCh6, '')
            ylabel(app.UIAxesCh6, 'Amplitude')
            app.UIAxesCh7 = uiaxes(app.GridLayout);
            title(app.UIAxesCh7, 'Channel 7')
            xlabel(app.UIAxesCh7, 'Time')
            ylabel(app.UIAxesCh7, 'Amplitude')
            app.Panel = uipanel(app.UIFigure);
            app.StartLabel = uilabel(app.Panel);
            app.StartLabel.Text = 'Start';
            app.StopLabel = uilabel(app.Panel);
            app.StopLabel.Text = 'Stop';
            app.SliderStart = uislider(app.Panel);
            app.SliderStart.MajorTicks = [];
            app.SliderStart.ValueChangedFcn = createCallbackFcn(app, @SliderStartValueChanged, true);
            app.SliderStart.MinorTicks = [];
            app.SliderStop = uislider(app.Panel);
            app.SliderStop.MajorTicks = [];
            app.SliderStop.ValueChangedFcn = createCallbackFcn(app, @SliderStopValueChanged, true);
            app.SliderStop.MinorTicks = [];
            app.EditorPanel = uipanel(app.UIFigure);
            app.EditorPanel.Title = 'Editor';
            app.SelectedLabel = uilabel(app.EditorPanel);
            app.SelectedLabel.Text = 'Selected: 0';
            app.DoneButton = uibutton(app.EditorPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.NextButton = uibutton(app.EditorPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.SelectDataButton = uibutton(app.EditorPanel, 'push');
            app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
            app.SelectDataButton.Text = 'Select Data';
            app.GroupActionButtonGroup = uibuttongroup(app.EditorPanel);
            app.GroupActionButtonGroup.Title = 'GroupAction';
            app.DeleteButton = uiradiobutton(app.GroupActionButtonGroup);
            app.DeleteButton.Text = 'Delete';
            app.SelectButton = uiradiobutton(app.GroupActionButtonGroup);
            app.SelectButton.Text = 'Select';
            app.SelectButton.Value = true;
            app.DisplayChannelsPanel = uipanel(app.EditorPanel);
            app.DisplayChannelsPanel.Title = 'Display Channels';
            app.Channel1CheckBox = uicheckbox(app.DisplayChannelsPanel);
            app.Channel1CheckBox.Text = 'Channel 1';
            app.Channel2CheckBox = uicheckbox(app.DisplayChannelsPanel);
            app.Channel2CheckBox.Text = 'Channel 2';
            app.Channel3CheckBox = uicheckbox(app.DisplayChannelsPanel);
            app.Channel3CheckBox.Text = 'Channel 3';
            app.Channel4CheckBox = uicheckbox(app.DisplayChannelsPanel);
            app.Channel4CheckBox.Text = 'Channel 4';
            app.Channel5CheckBox = uicheckbox(app.DisplayChannelsPanel);
            app.Channel5CheckBox.Text = 'Channel 5';
            app.Channel6CheckBox = uicheckbox(app.DisplayChannelsPanel);
            app.Channel6CheckBox.Text = 'Channel 6';
            app.Channel7CheckBox = uicheckbox(app.DisplayChannelsPanel);
            app.Channel7CheckBox.Text = 'Channel 7';
            app.Channel8CheckBox = uicheckbox(app.DisplayChannelsPanel);
            app.Channel8CheckBox.Text = 'Channel 8';
            app.Channel9CheckBox = uicheckbox(app.DisplayChannelsPanel);
            app.Channel9CheckBox.Text = 'Channel 9';
            app.Channel10CheckBox = uicheckbox(app.DisplayChannelsPanel);
            app.Channel10CheckBox.Text = 'Channel 10';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appDataEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end