classdef onlineClassificationGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        bgOnlineClassificationGUI  matlab.ui.Figure
        panelDisplay               matlab.ui.container.Panel
        dispStatus                 matlab.ui.control.Label
        buttonStartStop            matlab.ui.control.Button
        dispPrediction             matlab.ui.control.Label
        uipanel6                   matlab.ui.container.Panel
        dispThreshText             matlab.ui.control.Label
        tableThresh                matlab.ui.control.Table
        titleBurstLen              matlab.ui.control.Label
        inputWindowSize            matlab.ui.control.EditField
        text10                     matlab.ui.control.Label
        inputBlankSize             matlab.ui.control.EditField
        text11                     matlab.ui.control.Label
        inputArtefact              matlab.ui.control.Table
        chPopup1                   matlab.ui.control.DropDown
        chPopup2                   matlab.ui.control.DropDown
        textCOMPORT                matlab.ui.control.Label
        inputCOMPORT               matlab.ui.control.EditField
        textFilter                 matlab.ui.control.Label
        inputFilter                matlab.ui.control.Table
        buttonGroupChannelMode     matlab.ui.container.ButtonGroup
        radiobutton13              matlab.ui.control.RadioButton
        radiobutton14              matlab.ui.control.RadioButton
        panelClassificationMethod  matlab.ui.container.ButtonGroup
        rbThreshold                matlab.ui.control.RadioButton
        rbFeatures                 matlab.ui.control.RadioButton
        panelOption                matlab.ui.container.Panel
        buttonReselect             matlab.ui.control.Button
        buttonTrain                matlab.ui.control.Button
        buttonSaveFeatures         matlab.ui.control.Button
        dispThreshMult             matlab.ui.control.Label
        inputThreshMult            matlab.ui.control.Table
    end

    
    properties (Access = public)
        UserData
    end
    
    methods (Access = private)
        function getPythonClassifier(app, filepath)
            % train python classifier
            systemCmd = sprintf('python %s %s', fullfile('C:', 'classificationTraining.py'), filepath);
            system(systemCmd)
            
            popMsg('Saved Classifier...');
            
            % transfer classifier to rpi
            cwd = pwd;
            cd(filepath)
            savedClassifier = dir('*.sav');  % get all the saved classifier
            cd(cwd)
            
            for i = 1:length(savedClassifier)
                % IMPORTANT! download pscp in order to use this command
                try  % for Windows
                    systemCmd = sprintf('pscp -pw raspberry -scp %s pi@192.168.4.3:~/classificationTmp/', fullfile(filepath, savedClassifier(i,1).name));
                    status = system(systemCmd);
                    if ~status  % failed to transfer
                        popMsg('Successfully transfered file...');
                    else
                        popMsg('failed to transfer file...');
                        break
                    end
                catch
                    try  % for Linux
                        systemCmd = sprintf('sshpass -p raspberry scp %s pi@192.168.4.3:~/classificationTmp/', fullfile(filepath, savedClassifier(i,1).name));
                        system(systemCmd)
                    catch
                        popMsg('failed to transfer file...')
                        break
                    end
                end
            end
        end
        
        function resetAll(app)
            disp('Program stopped...')
            
            app.dispPrediction.Text = num2str(zeros(1,app.UserData.numChannelDisp));
            app.dispStatus.Text = 'Program stopped...';
            app.buttonStartStop.Text= 'Start';
            app.buttonStartStop.FontColor = [0,190/256,0];
            app.UserData.stopFlag = 1;
            app.UserData.openPortFlag = 0;
            
            app.tableThresh.Data = [];
            app.inputArtefact.Data = [];
            for i = 1:app.UserData.numChannelDisp
                app.tableThresh.Data{i,1} = Inf;
                app.inputArtefact.Data{i,1} = nan;
            end
            
            try
                fwrite(app.UserData.tB,[app.UserData.parameters.channelEnable,0]); % to disable all channels
            catch
            end
            
            try
                writeToHand(app.UserData.tH,'0');
                closeBionicHand(app.UserData.tH);
            catch
            end
            
            drawnow
            % guidata(hObject, handles);
        end
        
        function predictClassAll = runProgram(app, predictClassAll)
            % try
                for i = 1:app.UserData.parameters.numChannel
                    readSample(app.UserData.classInfo{i,1});
                    %             detectBurst(app.UserData.classInfo{i,1});
                    classifyBurst(app.UserData.classInfo{i,1});
                    if predictClassAll(1,i) ~= app.UserData.classInfo{i,1}.predictClass % update if state changed
                        timing = tic;
                        predictClassAll(1,i) = app.UserData.classInfo{i,1}.predictClass;
                        predictClassTemp = predictClassAll;
            %             if all(predictClassAll(1:2))
            %                 predictClassTemp(2) = 0;
            %             end
            %             if all(predictClassAll(3:4))
            %                 predictClassTemp(4) = 0;
            %             end
                        app.dispPrediction.Text = num2str(predictClassTemp);
                        replyPredictionDec = bi2de(predictClassTemp,'right-msb');
                        fwrite(app.UserData.tB,[app.UserData.parameters.channelEnable,replyPredictionDec]); % to enable the channel
                        drawnow
            
                        % write data to robot hand
                        if ~app.UserData.bionicHandConnection && ~isequal(app.UserData.portHand(4:end), 'nan')
                            try
                                if app.UserData.parameters.numChannel == 2 % add some space between the two channels
                                    writeToHand(app.UserData.tH,...
                                        num2str(...
                                        bi2de([predictClassTemp(1),[0,0],predictClassTemp(2)],'right-msb')...
                                        ));
                                else
                                    writeToHand(app.UserData.tH,num2str(replyPredictionDec))
                                end
                            catch
                                popMsg('Failed to write data to hand...');
                            end
                        end
            
                        drawnow
                        disp(toc(timing));
                    end
            
                    %             disp(['Class ',num2str(i),' prediction: ',num2str(app.UserData.classInfo{i,1}.predictClass)]);
                    %             elapsedTime{i,1} = [elapsedTime{i,1};toc(t)];
                end
            
            % catch
            %     resetAll(app)
            %     app.UserData.startAllFlag = 0;
            %     popMsg('Wrong selection, please start over...');
            %     drawnow
            % end
            drawnow
        end
        
        function filepath = saveBurstsInfo(app, signal, signalClassificationInfo, saveFileName, markBurstInAllChannels)
            featuresForClassification = [5,8];  % selected features for classification
            numFile = length(signalClassificationInfo);
            
            % Make a folder to store the features and corresponding classes
            filepath = fileparts(saveFileName{1,1});
            filepath = fullfile(filepath, 'classificationTmp');
            if ~exist(filepath,'file')
                mkdir(filepath);
            end
            
            [~, filename] = fileparts(saveFileName{1,1});
            
            % if markBurstInAllChannels
            %     timeStamps = time2string();
            %     for i = 1:numFile
            %         fullfilenameFeature = fullfile(filepath,sprintf('featuresMv%d_%s_%s.csv', signal(i,1).channel(1,i), filename, timeStamps));
            %         fullfilenameClass = fullfile(filepath,sprintf('classMv%d_%s_%s.csv', signal(i,1).channel(1,i), filename, timeStamps));
            %
            %     end
            %
            % else
                for i = 1:numFile
                    numClass = 2;
            
                    timeStamps = time2string();
                    fullfilenameFeature = fullfile(filepath,sprintf('featuresCh%d_%s_%s.csv', signal(i,1).channel(1,i), filename, timeStamps));
                    fullfilenameClass = fullfile(filepath,sprintf('classCh%d_%s_%s.csv', signal(i,1).channel(1,i), filename, timeStamps));
            
                    featureStruct = signalClassificationInfo(i,1).features;
                    featureStruct = rmfield(featureStruct, 'dataAnalysed');  % to make it able to build a full table
                    featureTable = struct2table(featureStruct);
            
                    featureRaw = table2array(featureTable(:,featuresForClassification));  % get the target features
                    featureRaw = featureRaw(:,i:numFile:end);  % get the target channel
                    feature = reshape(featureRaw,[],2);  % reshape into columns of different features
                    feature = omitNan(feature,2,'any');
            
                    % get corresponding class
                    class = [];
                    for j = 1:numClass
                        featureTemp = featureRaw(:,j);
                        class = vertcat(class, repmat(j, sum(all([~isnan(featureTemp), featureTemp ~= 0], 2)), 1));
                    end
            
                    % save features and classes
                    csvwrite(fullfilenameFeature, feature)
                    disp(['Saved ', fullfilenameFeature, '...']);
                    csvwrite(fullfilenameClass, class)
                    disp(['Saved ', fullfilenameClass, '...']);
                end
            % end
            
            popMsg('Features and Classes saving finished...');
        end
        
        function setupClassifier(app)
            %% Parameters
            switch app.UserData.numChannelDisp
                case 2
                    parameters = struct(...
                        'overlapWindowSize',50,... % ms
                        'ports',[app.UserData.chPorts.(app.chPopup1.Value),...
                        app.UserData.chPorts.(app.chPopup2.Value)],...
                        'replyPort',1300,...
                        'channelEnable',251);
                case 4
                    parameters = struct(...
                        'overlapWindowSize',50,... % ms
                        'ports',1343:1346,...
                        'replyPort',1300,...
                        'channelEnable',251);
            end
            
            parameters.numChannel = length(parameters.ports);
            
            for i = 1:parameters.numChannel
                classInfo{i,1} = classOnlineClassification(); % Initiatialize the object
            
                guiInput = struct(...
                    'predictionMethod',app.panelClassificationMethod.SelectedObject.Text,...
                    'thresholds',app.tableThresh.Data{i,1},...
                    'windowSize',str2num(app.inputWindowSize.Value),...
                    'blankSize',str2num(app.inputBlankSize.Value),...
                    'triggerThreshold',app.inputArtefact.Data{i,1},...
                    'highpassCutoffFreq',app.inputFilter.Data{1,1},...
                    'lowpassCutoffFreq',app.inputFilter.Data{2,1},...
                    'samplingFreq',17850);
            
                setBasicParameters(classInfo{i,1},app.UserData.classifierParameters{i,1},parameters,guiInput);
            
                setTcpip(classInfo{i,1},'127.0.0.1',parameters.ports(1,i),'NetworkRole','client','Timeout',1);
            
                % Streaming data
                tcpip(classInfo{i,1}); % open channel port
                openPort(classInfo{i,1});
            end
            
            % open reply port
            try
                tB = tcpip('127.0.0.1',parameters.replyPort,'NetworkRole','client','Timeout',1);
                disp(['Opened port ',num2str(parameters.channelEnable),' as reply port...'])
                fopen(tB);
            catch
                disp(['Reply port ',num2str(parameters.channelEnable),' is not open yet...'])
            end
            
            
            %%  Run the online classification
            %     clearvars -except parameters classInfo tB
            
            % elapsedTime = cell(parameters.numChannel,1);
            
            app.UserData.openPortFlag = 1;
            app.UserData.classInfo = classInfo;
            app.UserData.parameters = parameters;
            app.UserData.tB = tB;
        end
        
        function setupUserData(app)
            app.UserData = struct(...
                'startAllFlag', 0,...
                'stopFlag', 1,...
                'openPortFlag', 0,...
                'stopAll', 0,...
                'multiChannelFlag', 0,...
                'bionicHandConnection',0,...
                'portHand',sprintf('COM%s', app.inputCOMPORT.Value));
            app.UserData.chPorts = struct(...
                'Ch1',1340,...
                'Ch2',1341,...
                'Ch3',1342,...
                'Ch4',1343,...
                'Ch5',1344,...
                'Ch6',1345,...
                'Ch7',1346,...
                'Ch8',1347,...
                'Ch9',1348,...
                'Ch10',1349);
            
            % guidata(hObject, handles);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function onlineClassificationGUI_OpeningFcn(app)
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to onlineClassificationGUI (see VARARGIN)
            
            % Choose default command line output for onlineClassificationGUI
            % app.output = hObject;
            
            % Update handles structure
            % guidata(hObject, handles);
            
            % setup the UserData
            setupUserData(app);
            
            % close all serial port connection
            instrreset
            
            % change enablity of inputThresh
            if strcmp('Threshold', app.panelClassificationMethod.SelectedObject.Text)
                app.tableThresh.Enable = 'on';
            else
                app.tableThresh.Enable = 'off';
            end
            
            app.UserData.numChannelDisp = 2;
            app.tableThresh.Data = cell(app.UserData.numChannelDisp,1);
            app.inputThreshMult.Data = cell(1,app.UserData.numChannelDisp);
            app.inputArtefact.Data = cell(app.UserData.numChannelDisp,1);
            for i = 1:app.UserData.numChannelDisp
                app.tableThresh.Data{i,1} = Inf;
                app.inputArtefact.Data{i,1} = nan;
            end
            
            % Filter configuration
            app.inputFilter.Data = num2cell([100;7500]);
            app.inputFilter.RowName(3:end) = [];
            
            warning('off','all');

        end

        % Close request function: bgOnlineClassificationGUI
        function bgOnlineClassificationGUI_CloseRequestFcn(app, event)
            try
                fclose(handles.UserData.tB);
                for i = 1:length(handles.UserData.classInfo)
                    fwrite(handles.UserData.classInfo{i,1}.t,'DISCONNECT!!!!!!');
                    fclose(handles.UserData.classInfo{i,1}.t);
                end
            catch
            end
            % guidata(hObject, handles);
            % Hint: delete(hObject) closes the figure
            delete(app);
        end

        % Selection changed function: buttonGroupChannelMode
        function buttonGroupChannelMode_SelectionChangedFcn(app, event)
            app.UserData.numChannelDisp = str2num(app.buttonGroupChannelMode.SelectedObject.Text(1));
            resetAll(app);
        end

        % Button pushed function: buttonReselect
        function buttonReselect_Callback(app, event)
            disp(' ')
            try
                [files,path] = selectFiles('Select trained parameters .mat file...');
            
                popMsg('Reselect training files...')
                classifierParameters = load(fullfile(path,files{1,1}));
                classifierParameters = classifierParameters.varargin{1,1};
            
                resetAll(app);
            
                app.UserData.startAllFlag = 1;
            
                app.inputThreshMult.Data = checkSizeNTranspose(classifierParameters{1,1}.threshMultStr, 1);
            
                app.UserData.classifierParameters = classifierParameters;
            
                popMsg('Trained file selected...');
            catch
                popMsg('Reselct failed...');
            end
            % guidata(hObject, handles);
        end

        % Button pushed function: buttonSaveFeatures
        function buttonSaveFeatures_Callback(app, event)
            disp(' ')
            try
                [threshMultStr, signal, signalClassificationInfo, saveFileName, parameters] = onlineClassifierDetectBursts(handles);
                filepath = saveBurstsInfo(app, signal, signalClassificationInfo, saveFileName, parameters.markBurstInAllChannels);
                getPythonClassifier(app, filepath);
                app.inputThreshMult.Data = checkSizeNTranspose(threshMultStr, 1);
            catch
                popMsg('Error while saving features...');
                app.UserData.threshMultStr = '';
            end
            % guidata(hObject, handles);
        end

        % Button pushed function: buttonStartStop
        function buttonStartStop_Callback(app, event)
            switch app.UserData.stopFlag
                case 0
                    resetAll(app);
                case 1
                    if app.UserData.startAllFlag
                        disp('Program started...')
                        app.dispPrediciton.Text = num2str(zeros(1,app.UserData.numChannelDisp));
                        app.dispStatus.Text = 'Program started...';
                        app.buttonStartStop.Text = 'Stop';
                        app.buttonStartStop.FontColor = 'r';
                        app.UserData.openPortFlag = 0;
                        app.UserData.stopFlag = 0;
            
                        % guidata(hObject, handles);
            
                        if ~app.UserData.openPortFlag
                            setupClassifier(app);
                            % guidata(hObject, handles);
                        end
            
                        if ~app.UserData.bionicHandConnection && ~isequal(app.UserData.portHand(4:end), 'nan')
                            try
                                app.UserData.tH = bionicHand(app.UserData.portHand);
                                % guidata(hObject, handles)
                            catch
            %                     allPorts = instrfind;
            %                     serialPortsLocs = ismember(get(allPorts,'Type'),'serial');
            %                     allNames = get(allPorts,'Name');
            %                     serialPorts = cell2mat(allNames(serialPortsLocs));
            %                     helpdlgBox = helpdlg(serialPorts,'Available COMPORT:');
            %                     helpdlgBox.Position(3) = 210;
                                popMsg('Invalid COMPORT!');
                                resetAll(app);
                            end
                        end
            
                        predictClassAll = zeros(1, app.UserData.parameters.numChannel);
            
                        pause(2) % for robot hand to work properly
            
                        for i = 1:app.UserData.parameters.numChannel
                            flushinput(app.UserData.classInfo{i,1}.t);  % flush input data
                        end
            
                        while app.UserData.openPortFlag
                            predictClassAll = runProgram(app, predictClassAll);  % run classification
                            handles = guidata(hObject);
                        end
                        app.UserData.openPortFlag = 0;
            
                    else
                        popMsg('Select a trained .mat file first...');
                    end
                otherwise
                    disp('How did you get in here !?')
            end
        end

        % Button pushed function: buttonTrain
        function buttonTrain_Callback(app, event)
            disp(' ')
            try
                onlineClassifierTraining(handles);
                resetAll(app);
                popMsg('Training done...');
            catch
                resetAll(app);
                popMsg('Training failed...');
            end
            % % guidata(hObject, handles);
        end

        % Value changed function: chPopup1
        function chPopup1_Callback(app, event)
            % Hints: contents = cellstr(get(hObject,'String')) returns chPopup1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from chPopup1
            resetAll(app);
        end

        % Value changed function: chPopup2
        function chPopup2_Callback(app, event)
            % Hints: contents = cellstr(get(hObject,'String')) returns chPopup2 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from chPopup2
            resetAll(app);
        end

        % Cell edit callback: inputArtefact
        function inputArtefact_CellEditCallback(app, event)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.TABLE)
            %	Indices: row and column indices of the cell(s) edited
            %	PreviousData: previous data for the cell(s) edited
            %	EditData: string(s) entered by the user
            %	NewData: EditData or its converted form set on the Data property. Empty if Data was not changed
            %	Error: error string when failed to convert EditData to appropriate value for Data
            % resetAll(app);
            try
                for i = 1:length(app.inputArtefact.Data)
                    app.UserData.classInfo{i,1}.triggerThreshold = app.inputArtefact.Data{i,1};
                end
            catch
                popMsg('Failed in inputArtefact...');
            end
            
            % guidata(hObject, handles);
        end

        % Value changed function: inputBlankSize
        function inputBlankSize_Callback(app, event)
            % Hints: get(hObject,'String') returns contents of inputBlankSize as text
            %        str2double(get(hObject,'String')) returns contents of inputBlankSize as a double
            try
                for i = 1:length(app.UserData.numChannelDisp)
                    app.UserData.classInfo{i,1}.blankSize = str2num(app.inputBlankSize.Value);
                end
                popMsg(sprintf('Changed blank size to %d ms...', app.UserData.classInfo{i,1}.blankSize));
            catch
                popMsg('Failed to input blank size...');
            end
            
            resetAll(app);
        end

        % Value changed function: inputCOMPORT
        function inputCOMPORT_Callback(app, event)
            % Hints: get(hObject,'String') returns contents of inputCOMPORT as text
            %        str2double(get(hObject,'String')) returns contents of inputCOMPORT as a double
            app.UserData.portHand = sprintf('COM%s', app.inputCOMPORT.Value);
            resetAll(app);
        end

        % Cell edit callback: inputFilter
        function inputFilter_CellEditCallback(app, event)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.TABLE)
            %	Indices: row and column indices of the cell(s) edited
            %	PreviousData: previous data for the cell(s) edited
            %	EditData: string(s) entered by the user
            %	NewData: EditData or its converted form set on the Data property. Empty if Data was not changed
            %	Error: error string when failed to convert EditData to appropriate value for Data
            filterCutoffFreq = cell2mat(get(hObject,'Data'));
            if all(filterCutoffFreq > 0) && sign(diff(filterCutoffFreq)) == -1
                popMsg('Lowpass Cutoff Freq smaller than Highpass Cutoff Freq...');
                hObject.Data{2,1} = hObject.Data{1,1};
            end
            resetAll(app);
        end

        % Value changed function: inputWindowSize
        function inputWindowSize_Callback(app, event)
            % Hints: get(hObject,'String') returns contents of inputWindowSize as text
            %        str2double(get(hObject,'String')) returns contents of inputWindowSize as a double
            lowerLimit = 50;
            if str2num(app.inputWindowSize.Value) < lowerLimit
                errordlg('Window size smaller than lower limit...');
                app.inputWindowSize.Value = lowerLimit;
            end
            
            try
                for i = 1:length(app.UserData.numChannelDisp)
                    app.UserData.classInfo{i,1}.windowSize = str2num(app.inputWindowSize.Value);
                end
                popMsg(sprintf('Changed window size to %d ms...', app.UserData.classInfo{i,1}.windowSize));
            catch
                popMsg('Failed to change window size...');
            end
            
            resetAll(app);
        end

        % Selection changed function: panelClassificationMethod
        function panelClassificationMethod_SelectionChangedFcn(app, event)
            if strcmp('Threshold', app.panelClassificationMethod.SelectedObject.Text)
                app.tableThresh.Enable = 'on';
            else
                app.tableThresh.Enable = 'off';
            end
            resetAll(app);
        end

        % Cell edit callback: tableThresh
        function tableThresh_CellEditCallback(app, event)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.TABLE)
            %	Indices: row and column indices of the cell(s) edited
            %	PreviousData: previous data for the cell(s) edited
            %	EditData: string(s) entered by the user
            %	NewData: EditData or its converted form set on the Data property. Empty if Data was not changed
            %	Error: error string when failed to convert EditData to appropriate value for Data
            
            try
                for i = 1:length(app.inputArtefact.Data)
                    app.UserData.classInfo{i,1}.thresholds = app.tableThresh.Data{i,1};
                end
            catch
            end
            
            % guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.bgOnlineClassificationGUI = uifigure('Visible', 'off');
            app.bgOnlineClassificationGUI.Name = 'onlineClassificationGUI';
            app.bgOnlineClassificationGUI.CloseRequestFcn = createCallbackFcn(app, @bgOnlineClassificationGUI_CloseRequestFcn, true);
            app.panelDisplay = uipanel(app.bgOnlineClassificationGUI);
            app.panelDisplay.Title = 'Display';
            app.dispStatus = uilabel(app.panelDisplay);
            app.dispStatus.Text = 'Program stopped...';
            app.buttonStartStop = uibutton(app.panelDisplay, 'push');
            app.buttonStartStop.ButtonPushedFcn = createCallbackFcn(app, @buttonStartStop_Callback, true);
            app.buttonStartStop.Text = 'Start';
            app.dispPrediction = uilabel(app.panelDisplay);
            app.dispPrediction.Text = '0  0';
            app.uipanel6 = uipanel(app.bgOnlineClassificationGUI);
            app.uipanel6.Title = 'Input';
            app.dispThreshText = uilabel(app.uipanel6);
            app.dispThreshText.Text = 'SignalThresh';
            app.tableThresh = uitable(app.uipanel6);
            app.tableThresh.ColumnName = '';
            app.tableThresh.RowName = '';
            app.tableThresh.ColumnEditable = [true true true true];
            app.tableThresh.CellEditCallback = createCallbackFcn(app, @tableThresh_CellEditCallback, true);
            app.titleBurstLen = uilabel(app.uipanel6);
            app.titleBurstLen.Text = 'Window Size (ms)';
            app.inputWindowSize = uieditfield(app.uipanel6, 'text');
            app.inputWindowSize.ValueChangedFcn = createCallbackFcn(app, @inputWindowSize_Callback, true);
            app.inputWindowSize.Value = '100';
            app.text10 = uilabel(app.uipanel6);
            app.text10.Text = 'Blank Size (ms)';
            app.inputBlankSize = uieditfield(app.uipanel6, 'text');
            app.inputBlankSize.ValueChangedFcn = createCallbackFcn(app, @inputBlankSize_Callback, true);
            app.inputBlankSize.Value = '0';
            app.text11 = uilabel(app.uipanel6);
            app.text11.Text = 'ArtefactThresh';
            app.inputArtefact = uitable(app.uipanel6);
            app.inputArtefact.ColumnName = '';
            app.inputArtefact.RowName = '';
            app.inputArtefact.ColumnEditable = [true true true true];
            app.inputArtefact.CellEditCallback = createCallbackFcn(app, @inputArtefact_CellEditCallback, true);
            app.chPopup1 = uidropdown(app.uipanel6);
            app.chPopup1.Items = {'Ch1', 'Ch2', 'Ch3', 'Ch4', 'Ch5'};
            app.chPopup1.ValueChangedFcn = createCallbackFcn(app, @chPopup1_Callback, true);
            app.chPopup1.Value = 'Ch1';
            app.chPopup2 = uidropdown(app.uipanel6);
            app.chPopup2.Items = {'Ch6', 'Ch7', 'Ch8', 'Ch9', 'Ch10'};
            app.chPopup2.ValueChangedFcn = createCallbackFcn(app, @chPopup2_Callback, true);
            app.chPopup2.Value = 'Ch6';
            app.textCOMPORT = uilabel(app.uipanel6);
            app.textCOMPORT.Text = 'Serial COM PORT';
            app.inputCOMPORT = uieditfield(app.uipanel6, 'text');
            app.inputCOMPORT.ValueChangedFcn = createCallbackFcn(app, @inputCOMPORT_Callback, true);
            app.inputCOMPORT.Value = '47';
            app.textFilter = uilabel(app.uipanel6);
            app.textFilter.Text = 'Filter Setup (input 0 to disable)';
            app.inputFilter = uitable(app.uipanel6);
            app.inputFilter.ColumnName = '';
            app.inputFilter.RowName = {'Highpass Cutoff'; 'Lowpass Cutoff'; ''; ''};
            app.inputFilter.ColumnEditable = [true true];
            app.inputFilter.CellEditCallback = createCallbackFcn(app, @inputFilter_CellEditCallback, true);
            app.buttonGroupChannelMode = uibuttongroup(app.uipanel6);
            app.buttonGroupChannelMode.SelectionChangedFcn = createCallbackFcn(app, @buttonGroupChannelMode_SelectionChangedFcn, true);
            app.buttonGroupChannelMode.Title = 'Channe lMode';
            app.radiobutton13 = uiradiobutton(app.buttonGroupChannelMode);
            app.radiobutton13.Text = '2-channel';
            app.radiobutton13.Value = true;
            app.radiobutton14 = uiradiobutton(app.buttonGroupChannelMode);
            app.radiobutton14.Text = '4-channel';
            app.panelClassificationMethod = uibuttongroup(app.uipanel6);
            app.panelClassificationMethod.SelectionChangedFcn = createCallbackFcn(app, @panelClassificationMethod_SelectionChangedFcn, true);
            app.panelClassificationMethod.Title = 'Classify Method';
            app.rbThreshold = uiradiobutton(app.panelClassificationMethod);
            app.rbThreshold.Text = 'Threshold';
            app.rbThreshold.Value = true;
            app.rbFeatures = uiradiobutton(app.panelClassificationMethod);
            app.rbFeatures.Text = 'Features';
            app.panelOption = uipanel(app.bgOnlineClassificationGUI);
            app.panelOption.Title = 'Action';
            app.buttonReselect = uibutton(app.panelOption, 'push');
            app.buttonReselect.ButtonPushedFcn = createCallbackFcn(app, @buttonReselect_Callback, true);
            app.buttonReselect.Text = 'Reselect';
            app.buttonTrain = uibutton(app.panelOption, 'push');
            app.buttonTrain.ButtonPushedFcn = createCallbackFcn(app, @buttonTrain_Callback, true);
            app.buttonTrain.Text = 'Train';
            app.buttonSaveFeatures = uibutton(app.panelOption, 'push');
            app.buttonSaveFeatures.ButtonPushedFcn = createCallbackFcn(app, @buttonSaveFeatures_Callback, true);
            app.buttonSaveFeatures.Text = 'Save Features';
            app.dispThreshMult = uilabel(app.panelOption);
            app.dispThreshMult.Text = 'ThreshMult';
            app.inputThreshMult = uitable(app.panelOption);
            app.inputThreshMult.ColumnName = '';
            app.inputThreshMult.RowName = '';
            app.inputThreshMult.ColumnEditable = [true true true true];
            app.inputThreshMult.Enable = 'off';
            % Show the figure after all components are created
            app.bgOnlineClassificationGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = onlineClassificationGUI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.bgOnlineClassificationGUI)

            % Execute the startup function
            runStartupFcn(app, @onlineClassificationGUI_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.bgOnlineClassificationGUI)
        end
    end
end